classdef preprocessingAdvancedSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdvancedSettingsUIFigure       matlab.ui.Figure
        AdvancedSettingsLabel          matlab.ui.control.Label
        ContrastcorrectionSwitchLabel  matlab.ui.control.Label
        ContrastcorrectionSwitch       matlab.ui.control.Switch
        ContrastcorrectbyButtonGroup   matlab.ui.container.ButtonGroup
        patchButton                    matlab.ui.control.ToggleButton
        imageButton                    matlab.ui.control.ToggleButton
        InputdatadownsamplingSwitchLabel  matlab.ui.control.Label
        InputdatadownsamplingSwitch    matlab.ui.control.Switch
        DownsamplingfactorEditFieldLabel  matlab.ui.control.Label
        DownsamplingfactorEditField    matlab.ui.control.NumericEditField
        DoneButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main mEMbrain app object
        contrastCorrect
        downsamplingFactor
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main mEMbrain app object
            app.CallingApp = mainapp;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Collect user input data and pass back to main app
            
            if app.ContrastcorrectionSwitch.Value == "On"
                switch app.patchButton.Value
                    case 1
                        app.contrastCorrect = "patch";
                    otherwise
                        app.contrastCorrect = "image";
                end
            else
                app.contrastCorrect = "none";
            end
            
            if app.InputdatadownsamplingSwitch.Value == "On"
               app.downsamplingFactor = app.DownsamplingfactorEditField.Value; 
            end
            
            app.CallingApp.updateAdvancedSettings(app.contrastCorrect, app.downsamplingFactor);
            
            app.CallingApp.advancedSettingsButton.Enable = "on";
            delete(app)
            
        end

        % Close request function: AdvancedSettingsUIFigure
        function AdvancedSettingsUIFigureCloseRequest(app, event)
            disp("here")
            app.CallingApp.advancedSettingsButton.Enable = "on";
            delete(app)
        end

        % Value changed function: ContrastcorrectionSwitch
        function ContrastcorrectionSwitchValueChanged(app, event)
            value = app.ContrastcorrectionSwitch.Value;
            if value == "On"
                app.patchButton.Enable = "on";
                app.imageButton.Enable = "on";
            else
                app.patchButton.Enable = "off";
                app.imageButton.Enable = "off";
            end
        end

        % Value changed function: InputdatadownsamplingSwitch
        function InputdatadownsamplingSwitchValueChanged(app, event)
            value = app.InputdatadownsamplingSwitch.Value;
            if value == "On"
                app.DownsamplingfactorEditField.Enable = "on";
            else
                app.DownsamplingfactorEditField.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdvancedSettingsUIFigure = uifigure('Visible', 'off');
            app.AdvancedSettingsUIFigure.Name = 'Advanced Settings';
            app.AdvancedSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @AdvancedSettingsUIFigureCloseRequest, true);
            app.AdvancedSettingsLabel = uilabel(app.AdvancedSettingsUIFigure);
            app.AdvancedSettingsLabel.Text = 'Advanced Settings';
            app.ContrastcorrectionSwitchLabel = uilabel(app.AdvancedSettingsUIFigure);
            app.ContrastcorrectionSwitchLabel.Text = 'Contrast correction';
            app.ContrastcorrectionSwitch = uiswitch(app.AdvancedSettingsUIFigure, 'slider');
            app.ContrastcorrectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ContrastcorrectionSwitchValueChanged, true);
            app.ContrastcorrectbyButtonGroup = uibuttongroup(app.AdvancedSettingsUIFigure);
            app.ContrastcorrectbyButtonGroup.Title = 'Contrast correct by...';
            app.patchButton = uitogglebutton(app.ContrastcorrectbyButtonGroup);
            app.patchButton.Enable = 'off';
            app.patchButton.Text = 'Patch';
            app.patchButton.Value = true;
            app.imageButton = uitogglebutton(app.ContrastcorrectbyButtonGroup);
            app.imageButton.Enable = 'off';
            app.imageButton.Text = 'Entire Image';
            app.InputdatadownsamplingSwitchLabel = uilabel(app.AdvancedSettingsUIFigure);
            app.InputdatadownsamplingSwitchLabel.Text = 'Input data downsampling';
            app.InputdatadownsamplingSwitch = uiswitch(app.AdvancedSettingsUIFigure, 'slider');
            app.InputdatadownsamplingSwitch.ValueChangedFcn = createCallbackFcn(app, @InputdatadownsamplingSwitchValueChanged, true);
            app.DownsamplingfactorEditFieldLabel = uilabel(app.AdvancedSettingsUIFigure);
            app.DownsamplingfactorEditFieldLabel.Text = 'Downsampling factor';
            app.DownsamplingfactorEditField = uieditfield(app.AdvancedSettingsUIFigure, 'numeric');
            app.DownsamplingfactorEditField.Enable = 'off';
            app.DownsamplingfactorEditField.Value = 1;
            app.DoneButton = uibutton(app.AdvancedSettingsUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.AdvancedSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preprocessingAdvancedSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdvancedSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdvancedSettingsUIFigure)
        end
    end
end