classdef vastControlDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        vastControlDialogFigure  matlab.ui.Figure
        SelectVASTControlDirectoryButton  matlab.ui.control.Button
        PleaseselectthepathoftheVASTControldirectoryonyourcomputerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: SelectVASTControlDirectoryButton
        function SelectVASTControlDirectoryButtonPushed(app, event)
            selectVastDir = uigetdir;
            drawnow;
            figure(app.vastControlDialogFigure) % Brings mEMbrain app back into focus
            if selectVastDir ~= 0
                app.CallingApp.vastControlDirectory = selectVastDir;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.vastControlDialogFigure = uifigure('Visible', 'off');
            app.vastControlDialogFigure.Name = 'UI Figure';
            app.PleaseselectthepathoftheVASTControldirectoryonyourcomputerLabel = uilabel(app.vastControlDialogFigure);
            app.PleaseselectthepathoftheVASTControldirectoryonyourcomputerLabel.Text = 'Please select the path of the VASTControl directory on your computer.';
            app.SelectVASTControlDirectoryButton = uibutton(app.vastControlDialogFigure, 'push');
            app.SelectVASTControlDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVASTControlDirectoryButtonPushed, true);
            app.SelectVASTControlDirectoryButton.Text = 'Select VASTControl Directory';
            % Show the figure after all components are created
            app.vastControlDialogFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vastControlDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.vastControlDialogFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.vastControlDialogFigure)
        end
    end
end